classdef Datos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatosScreen         matlab.ui.Figure
        Image2              matlab.ui.control.Image
        GuardartablaButton  matlab.ui.control.Button
        Image               matlab.ui.control.Image
        VolverButton        matlab.ui.control.Button
        Tabla               matlab.ui.control.Table
    end

    
    properties (Access = public)
        datosTabla; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            datos = readtable("Admission_Predict.xlsx");
            app.Tabla.Data = datos;
            app.datosTabla = datos;
            % Agregando el nombre de las columnas
            columnas = datos.Properties.VariableNames;
            app.Tabla.ColumnName = columnas;
        end

        % Callback function
        function ActualizarTablaButtonPushed(app, event)

        end

        % Button pushed function: GuardartablaButton
        function GuardartablaButtonPushed(app, event)
            app.datosTabla = app.Tabla.Data;
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            Grafica;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DatosScreen = uifigure('Visible', 'off');
            app.DatosScreen.Name = 'MATLAB App';
            app.Tabla = uitable(app.DatosScreen);
            app.Tabla.ColumnName = {'Serial No.'; 'GRE Score'; 'TOEFL Score'; 'University Rating'; 'SOP'; 'LOR'; 'CGPA'; 'Research'; 'Chance to Admit'};
            app.Tabla.RowName = {};
            app.Tabla.ColumnEditable = true;
            app.VolverButton = uibutton(app.DatosScreen, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.Image = uiimage(app.DatosScreen);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'cheemsPan.gif');
            app.GuardartablaButton = uibutton(app.DatosScreen, 'push');
            app.GuardartablaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardartablaButtonPushed, true);
            app.GuardartablaButton.Text = 'Guardar tabla';
            app.Image2 = uiimage(app.DatosScreen);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'img', 'cheemsRanita.gif');
            % Show the figure after all components are created
            app.DatosScreen.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Datos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatosScreen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatosScreen)
        end
    end
end