classdef Grafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        ProbabilidadEditField           matlab.ui.control.NumericEditField
        ProbabilidadEditFieldLabel      matlab.ui.control.Label
        GREEditField                    matlab.ui.control.NumericEditField
        GREEditFieldLabel               matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        EcuacinEditField                matlab.ui.control.EditField
        EcuacinEditFieldLabel           matlab.ui.control.Label
        ColorMallasDropDown             matlab.ui.control.DropDown
        ColorMallasDropDownLabel        matlab.ui.control.Label
        TeoraButton                     matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ColorfondoDropDown              matlab.ui.control.DropDown
        ColorfondoDropDownLabel         matlab.ui.control.Label
        VerModificarTablasButton        matlab.ui.control.Button
        BordeButton                     matlab.ui.control.StateButton
        EjexButton                      matlab.ui.control.StateButton
        EsteprogramapuedecalcularLabel  matlab.ui.control.Label
        VolverButton                    matlab.ui.control.Button
        CalcularButton                  matlab.ui.control.Button
        EjeyButton                      matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dattos)
            %datos = dattos;
        end

        % Value changed function: EjexButton
        function EjexButtonValueChanged(app, event)
            value = app.EjexButton.Value;
            if app.UIAxes.XGrid == "off"
                app.UIAxes.XGrid = "on";
            else
                app.UIAxes.XGrid = "off";
            end
        end

        % Value changed function: EjeyButton
        function EjeyButtonValueChanged(app, event)
            value = app.EjeyButton.Value;
            if app.UIAxes.YGrid == "off"
                app.UIAxes.YGrid = "on";
            else
                app.UIAxes.YGrid = "off";
            end
        end

        % Value changed function: BordeButton
        function BordeButtonValueChanged(app, event)
            value = app.BordeButton.Value;
            if app.UIAxes.Box == "off"
                app.UIAxes.Box = "on";
            else
                app.UIAxes.Box = "off";
            end
        end

        % Value changed function: ColorfondoDropDown
        function ColorfondoDropDownValueChanged(app, event)
            value = app.ColorfondoDropDown.Value;
            if value == "Piel"
                app.UIAxes.Color = "#FFDFD3";
            end
            if value == "Rosita"
                app.UIAxes.Color = "#FEC8D8";
            end
            if value == "Lila"
                app.UIAxes.Color = "#E0BBE4";
            end
            if value == "Rosota"
                app.UIAxes.Color = "#D291BC";
            end
            if value == "Blanco"
                app.UIAxes.Color = "#FFFFFF";
            end

        end

        % Value changed function: ColorMallasDropDown
        function ColorMallasDropDownValueChanged(app, event)
            value = app.ColorMallasDropDown.Value;
            if value == "Rosa"
                app.UIAxes.GridColor = "magenta";
            end
            if value == "Rojo"
                app.UIAxes.GridColor = "#A2142F";
            end
            if value == "Verde"
                app.UIAxes.GridColor = "#77AC30";
            end
            if value == "Azul"
                app.UIAxes.GridColor = "#0000FF";
            end
            if value == "Morado"
                app.UIAxes.GridColor = "#7E2F8E";
            end

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
        datos = readtable("Admission_Predict.xlsx");
        x = table2array(datos(:, 2));
        y = table2array(datos(:, 9));
        
        % Ordenar los datos en x de manera ascendente
        [x_sorted, sort_idx] = sort(x);
        y_sorted = y(sort_idx);
        
        % Ajuste de regresión lineal
        coefs = polyfit(x_sorted, y_sorted, 1);
        pendiente = coefs(1);
        intercepto = coefs(2);
        
        % Graficar puntos de datos y línea de regresión en la misma gráfica
        hold(app.UIAxes, 'on');  % Mantener el modo de espera en el objeto de gráfico existente
        
        % Graficar puntos de datos
        scatter(app.UIAxes, x, y, 'o', 'filled', 'MarkerFaceColor', 'b');
        
        % Calcular valores predichos
        y_pred = pendiente * x_sorted + intercepto;
        
        % Graficar línea de regresión
        plot(app.UIAxes, x_sorted, y_pred, 'r', 'LineWidth', 2);
        
        % Personalizar etiquetas y título
        xlabel(app.UIAxes, 'Eje X');
        ylabel(app.UIAxes, 'Eje Y');
        title(app.UIAxes, 'Regresión Lineal');
        
        % Mostrar leyenda
        legend(app.UIAxes, {'Datos', 'Regresión Lineal'});
        
        % Quitar el modo de espera
        hold(app.UIAxes, 'off');

% Actualizar la caja de texto con la ecuación de la regresión lineal
ecuacion = sprintf('y = %.4f * x + %.4f', pendiente, intercepto);
app.EcuacinEditField.Value = ecuacion;

        end

        % Button pushed function: TeoraButton
        function TeoraButtonPushed(app, event)
            Teoria;
            delete(app);
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            Proyecto;
            delete(app);
        end

        % Button pushed function: VerModificarTablasButton
        function VerModificarTablasButtonPushed(app, event)
            Datos;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'REGRESIÓN LINEAL')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'cool')
            app.EjeyButton = uibutton(app.UIFigure, 'state');
            app.EjeyButton.ValueChangedFcn = createCallbackFcn(app, @EjeyButtonValueChanged, true);
            app.EjeyButton.Text = 'Eje y';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.EsteprogramapuedecalcularLabel = uilabel(app.UIFigure);
            app.EsteprogramapuedecalcularLabel.Text = 'Opciones de gráfica:';
            app.EjexButton = uibutton(app.UIFigure, 'state');
            app.EjexButton.ValueChangedFcn = createCallbackFcn(app, @EjexButtonValueChanged, true);
            app.EjexButton.Text = 'Eje x';
            app.BordeButton = uibutton(app.UIFigure, 'state');
            app.BordeButton.ValueChangedFcn = createCallbackFcn(app, @BordeButtonValueChanged, true);
            app.BordeButton.Text = 'Borde';
            app.VerModificarTablasButton = uibutton(app.UIFigure, 'push');
            app.VerModificarTablasButton.ButtonPushedFcn = createCallbackFcn(app, @VerModificarTablasButtonPushed, true);
            app.VerModificarTablasButton.Text = 'Ver/Modificar Tablas';
            app.ColorfondoDropDownLabel = uilabel(app.UIFigure);
            app.ColorfondoDropDownLabel.Text = 'Color fondo';
            app.ColorfondoDropDown = uidropdown(app.UIFigure);
            app.ColorfondoDropDown.Items = {'Piel', 'Lila', 'Rosita', 'Rosota', 'Blanco'};
            app.ColorfondoDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorfondoDropDownValueChanged, true);
            app.ColorfondoDropDown.Value = 'Piel';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'cheemsPan.gif');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'img', 'cheemsPan.gif');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'img', 'cheemsPray.gif');
            app.TeoraButton = uibutton(app.UIFigure, 'push');
            app.TeoraButton.ButtonPushedFcn = createCallbackFcn(app, @TeoraButtonPushed, true);
            app.TeoraButton.Text = 'Teoría';
            app.ColorMallasDropDownLabel = uilabel(app.UIFigure);
            app.ColorMallasDropDownLabel.Text = 'Color Mallas';
            app.ColorMallasDropDown = uidropdown(app.UIFigure);
            app.ColorMallasDropDown.Items = {'Rosa', 'Rojo', 'Verde', 'Azul', 'Morado'};
            app.ColorMallasDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMallasDropDownValueChanged, true);
            app.ColorMallasDropDown.Value = 'Rosa';
            app.EcuacinEditFieldLabel = uilabel(app.UIFigure);
            app.EcuacinEditFieldLabel.Text = 'Ecuación:';
            app.EcuacinEditField = uieditfield(app.UIFigure, 'text');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.GREEditFieldLabel = uilabel(app.UIFigure);
            app.GREEditFieldLabel.Text = 'GRE';
            app.GREEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProbabilidadEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilidadEditFieldLabel.Text = 'Probabilidad';
            app.ProbabilidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grafica(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end